classdef PARAFACforMATLB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        EEMviewTab                matlab.ui.container.Tab
        SampleNLabel              matlab.ui.control.Label
        SampleN                   matlab.ui.control.NumericEditField
        ComfirmButton             matlab.ui.control.Button
        SSETab                    matlab.ui.container.Tab
        CompareSSELabel           matlab.ui.control.Label
        ComfirmButton_2           matlab.ui.control.Button
        ModelOutTab               matlab.ui.container.Tab
        ComponentNEditFieldLabel  matlab.ui.control.Label
        ComponentN                matlab.ui.control.NumericEditField
        ComfirmButton_3           matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function readineems = readineems(app)
            
        end
        
        function readinscans = readinscans(app)
            
        end
      
        function matchsamples = matchsamples(app)
            
        end
        
        function FDOMcorrect = FDOMcorrect(app)
            
        end
    end
    

    methods (Access = private)

        % Button pushed function: ComfirmButton
        function ComfirmButtonPushed(app, event)
            cd 'C:\PARAFAC\EEM'
            filetype=1;ext = 'csv';RangeIn='A1..AL262';headers=[1 1];display_opt=0;outdat=1;
            [X,Emmat,Exmat,filelist_eem,outdata]=readineems(filetype,ext,RangeIn,headers,display_opt,outdat);
            Ex=Exmat(1,:);
            Em=Emmat(:,1);
            
            
            cd 'C:\PARAFAC\WATER'
            filetype=1;ext = 'csv';RangeIn='A1..AL262';headers=[1 1];display_opt=0;outdat=1;
            [X_b,Emmat_b,Exmat_b,filelist_b,outdata_b]=ReadInEEMs(filetype,ext,RangeIn,headers,display_opt,outdat);
            Exb=Exmat_b(1,:);
            Emb=Emmat_b(:,1);
            
            cd 'C:\PARAFAC\RAMAN'
            RamEx=350;
            filetype='Raman275';
            ext = 'csv';RangeIn='A1..B166';display_opt=0;outdat=1;
            [S_R,W_R,wave_R,filelist_R]=readinscans(filetype,ext,RangeIn,display_opt,outdat);
            
            cd 'C:\PARAFAC\UV'
            filetype='Abs'; ext='csv'; RangeIn='A1..B351';
            display_opt=0; outdat=1;
            [S_abs,W_abs,wave_abs,filelist_abs]=readinscans(filetype,ext,RangeIn,display_opt,outdat);
            
            cd 'C:\PARAFAC\Correction'
            Excor=xlsread('CorEX.csv');
            Emcor=xlsread('CorEM.csv');
            
            
            cd 'C:\PARAFAC'
            [LogNUM,LogTXT]=xlsread('SampleID.xlsx','SF-6-P');
            
            Log_EEMfile=LogTXT(:,7);
            Log_ABSfile=LogTXT(:,8);
            Log_Blkfile=LogTXT(:,9);
            Log_RAMfile=LogTXT(:,10);
            
            Pair_EEM_abs=[Log_EEMfile Log_ABSfile];
            Pair_EEM_R=[Log_EEMfile Log_RAMfile];
            Pair_EEM_bk=[Log_EEMfile Log_Blkfile];
            Pair_BLK_R=[Log_Blkfile Log_RAMfile];
            
            
            [Xabs,PL]=matchsamples(filelist_eem,filelist_abs,Pair_EEM_abs,X,S_abs);
            [Sr,PLr]=matchsamples(filelist_eem,filelist_R,Pair_EEM_R,X,S_R);
            [Xbk,PLbk]=matchsamples(filelist_eem,filelist_b,Pair_EEM_bk,X,X_b);
            [Sbk_R,PLbk_R]=matchsamples(filelist_b,filelist_R,Pair_BLK_R,X_b,S_R);
            
            A=[wave_abs;Xabs]
            B=Xbk;
            W=[wave_R;Sr];
            T=[wave_R;Sbk_R];


            [XcRU Arp IFCmat BcRU XcQS QS_RU]=FDOMcorrect(X,Ex,Em,Emcor,Excor,W,[],A,B,T,[],[]);


            originaldata.Ex=Ex';
            originaldata.Em=Em;
            originaldata.X=XcRU;
            originaldata.nEx=length(Ex);
            originaldata.nEm=length(Em);
            originaldata.nSample=size(X,1);
            Xs=smootheem(originaldata,[18 15],[15 15],[17 18],[19 18],0);
            contourf(Xs.Ex,Xs.Em,(squeeze(Xs.X(app.SampleN.Value,:,:)))), colorbar
            xlabel('Ex. (nm)')
            ylabel('Em. (nm)')
            ylabel(colorbar,'RU');
            title(['sample nr ' num2str(app.SampleN.Value)]),
        end

        % Button pushed function: ComfirmButton_2
        function ComfirmButton_2Pushed(app, event)
            cd 'C:\PARAFAC\EEM'
            filetype=1;ext = 'csv';RangeIn='A1..AL262';headers=[1 1];display_opt=0;outdat=1;
            [X,Emmat,Exmat,filelist_eem,outdata]=ReadInEEMs(filetype,ext,RangeIn,headers,display_opt,outdat);
            Ex=Exmat(1,:);
            Em=Emmat(:,1);
            
            
            cd 'C:\PARAFAC\WATER'
            filetype=1;ext = 'csv';RangeIn='A1..AL262';headers=[1 1];display_opt=0;outdat=1;
            [X_b,Emmat_b,Exmat_b,filelist_b,outdata_b]=ReadInEEMs(filetype,ext,RangeIn,headers,display_opt,outdat);
            Exb=Exmat_b(1,:);
            Emb=Emmat_b(:,1);
            
            
            cd 'C:\PARAFAC\RAMAN'
            RamEx=350;
            filetype='Raman275';
            ext = 'csv';RangeIn='A1..B166';display_opt=0;outdat=1;
            [S_R,W_R,wave_R,filelist_R]=ReadInScans(filetype,ext,RangeIn,display_opt,outdat);
            
            cd 'C:\PARAFAC\UV'
            filetype='Abs'; ext='csv'; RangeIn='A1..B351';
            display_opt=0; outdat=1;
            [S_abs,W_abs,wave_abs,filelist_abs]=readinscans(filetype,ext,RangeIn,display_opt,outdat);
            
            cd 'C:\PARAFAC\Correction'
            Excor=xlsread('CorEX.csv');
            Emcor=xlsread('CorEM.csv');
            
            
            cd 'C:\PARAFAC'
            [LogNUM,LogTXT]=xlsread('SampleID.xlsx','SF-6-P');
            
            Log_EEMfile=LogTXT(:,7);
            Log_ABSfile=LogTXT(:,8);
            Log_Blkfile=LogTXT(:,9);
            Log_RAMfile=LogTXT(:,10);
            
            Pair_EEM_abs=[Log_EEMfile Log_ABSfile];
            Pair_EEM_R=[Log_EEMfile Log_RAMfile];
            Pair_EEM_bk=[Log_EEMfile Log_Blkfile];
            Pair_BLK_R=[Log_Blkfile Log_RAMfile];
            
            
            [Xabs,PL]=matchsamples(filelist_eem,filelist_abs,Pair_EEM_abs,X,S_abs);
            [Sr,PLr]=matchsamples(filelist_eem,filelist_R,Pair_EEM_R,X,S_R);
            [Xbk,PLbk]=matchsamples(filelist_eem,filelist_b,Pair_EEM_bk,X,X_b);
            [Sbk_R,PLbk_R]=matchsamples(filelist_b,filelist_R,Pair_BLK_R,X_b,S_R);
            
            A=[wave_abs;Xabs]
            B=Xbk;
            W=[wave_R;Sr];
            T=[wave_R;Sbk_R];


            [XcRU Arp IFCmat BcRU XcQS QS_RU]=FDOMcorrect(X,Ex,Em,Emcor,Excor,W,[],A,B,T,[],[]);


            originaldata.Ex=Ex';
            originaldata.Em=Em;
            originaldata.X=XcRU;
            originaldata.nEx=length(Ex);
            originaldata.nEm=length(Em);
            originaldata.nSample=size(X,1);
            Xs=smootheem(originaldata,[18 15],[15 15],[17 18],[19 18],0);
            [AnalysisData]=SplitData(Xs);
            [AnalysisData]=SplitHalfAnalysis(AnalysisData,(3:7),'MyData.mat');
            SplitHalfValidation(AnalysisData,'1-2',3);
            SplitHalfValidation(AnalysisData,'3-4',3);
            [AnalysisData]=RandInitAnal(AnalysisData,3,5);
            
            SplitHalfValidation(AnalysisData,'1-2',4);
            SplitHalfValidation(AnalysisData,'3-4',4);
            [AnalysisData]=RandInitAnal(AnalysisData,4,5);
            
            SplitHalfValidation(AnalysisData,'1-2',5);
            SplitHalfValidation(AnalysisData,'3-4',5);
            [AnalysisData]=RandInitAnal(AnalysisData,5,5);
            
            SplitHalfValidation(AnalysisData,'1-2',6);
            SplitHalfValidation(AnalysisData,'3-4',6);
            [AnalysisData]=RandInitAnal(AnalysisData,6,5);
            
            SplitHalfValidation(AnalysisData,'1-2',7);
            SplitHalfValidation(AnalysisData,'3-4',7);
            [AnalysisData]=RandInitAnal(AnalysisData,7,5);
            
            eval(['ModelA=','AnalysisData.Model',int2str(3)]);
            eval(['ModelB=','AnalysisData.Model',int2str(4)]);
            eval(['ModelC=','AnalysisData.Model',int2str(5)]);
            eval(['ModelD=','AnalysisData.Model',int2str(6)]);
            eval(['ModelE=','AnalysisData.Model',int2str(7)]);


            MA =nmodel(ModelA);
            EA=AnalysisData.X-MA;
            MA=AnalysisData.X-EA;
            
            MB =nmodel(ModelB);
            EB=AnalysisData.X-MB;
            MB=AnalysisData.X-EB;
            
            MC =nmodel(ModelC);
            EC=AnalysisData.X-MC;
            MC=AnalysisData.X-EC;
            
            MD =nmodel(ModelD);
            ED=AnalysisData.X-MD;
            MD=AnalysisData.X-ED;
            
            ME =nmodel(ModelE);
            EE=AnalysisData.X-ME;
            ME=AnalysisData.X-EE;
            
            
            intE_em=permute(EA,[3 1 2]);
            intE_em=intE_em.*intE_em; %Squared
            intE_em=misssum(intE_em); %Sum
            intE_em=squeeze(intE_em);
            intE_emA=misssum(intE_em); %Sum
            
            intE_ex=permute(EA,[2 1 3]);
            intE_ex=intE_ex.*intE_ex;
            intE_ex=misssum(intE_ex);
            intE_ex=squeeze(intE_ex);
            intE_exA=misssum(intE_ex);
            
            intE_em=permute(EB,[3 1 2]);
            intE_em=intE_em.*intE_em;
            intE_em=misssum(intE_em);
            intE_em=squeeze(intE_em);
            intE_emB=misssum(intE_em);
            
            intE_ex=permute(EB,[2 1 3]);
            intE_ex=intE_ex.*intE_ex;
            intE_ex=misssum(intE_ex);
            intE_ex=squeeze(intE_ex);
            intE_exB=misssum(intE_ex);
            
            intE_em=permute(EC,[3 1 2]);
            intE_em=intE_em.*intE_em;
            intE_em=misssum(intE_em);
            intE_em=squeeze(intE_em);
            intE_emC=misssum(intE_em);
            
            intE_ex=permute(EC,[2 1 3]);
            intE_ex=intE_ex.*intE_ex;
            intE_ex=misssum(intE_ex);
            intE_ex=squeeze(intE_ex);
            intE_exC=misssum(intE_ex);
            
            intE_em=permute(ED,[3 1 2]);
            intE_em=intE_em.*intE_em;
            intE_em=misssum(intE_em);
            intE_em=squeeze(intE_em);
            intE_emD=misssum(intE_em);
            
            intE_ex=permute(ED,[2 1 3]);
            intE_ex=intE_ex.*intE_ex;
            intE_ex=misssum(intE_ex);
            intE_ex=squeeze(intE_ex);
            intE_exD=misssum(intE_ex);
            
            intE_em=permute(EE,[3 1 2]);
            intE_em=intE_em.*intE_em;
            intE_em=misssum(intE_em);
            intE_em=squeeze(intE_em);
            intE_emE=misssum(intE_em);
            
            intE_ex=permute(EE,[2 1 3]);
            intE_ex=intE_ex.*intE_ex;
            intE_ex=misssum(intE_ex);
            intE_ex=squeeze(intE_ex);
            intE_exE=misssum(intE_ex);
            
            
            subplot(2,1,1),
            plot(AnalysisData.Ex,intE_exA(1,:),AnalysisData.Ex,intE_exB(1,:),AnalysisData.Ex,intE_exC(1,:),AnalysisData.Ex,intE_exD(1,:),AnalysisData.Ex,intE_exE(1,:))
            legend([num2str(3) ' Components'],[num2str(4) ' Components'],[num2str(5) ' Components'],[num2str(6) ' Components'],[num2str(7) ' Components'])
            axis tight
            xlabel('Ex. (nm)')
            ylabel('Sum of Squared Error')
            subplot(2,1,2),
            plot(AnalysisData.Em,intE_emA(1,:),AnalysisData.Em,intE_emB(1,:),AnalysisData.Em,intE_emC(1,:),AnalysisData.Em,intE_emD(1,:),AnalysisData.Em,intE_emE(1,:))
            axis tight
            xlabel('Em. (nm)')
            ylabel('Sum of Squared Error')
            
        end

        % Button pushed function: ComfirmButton_3
        function ComfirmButton_3Pushed(app, event)
            cd 'C:\PARAFAC\EEM'
            filetype=1;ext = 'csv';RangeIn='A1..AL262';headers=[1 1];display_opt=0;outdat=1;
            [X,Emmat,Exmat,filelist_eem,outdata]=ReadInEEMs(filetype,ext,RangeIn,headers,display_opt,outdat);
            Ex=Exmat(1,:);
            Em=Emmat(:,1);
            
            
            cd 'C:\PARAFAC\WATER'
            filetype=1;ext = 'csv';RangeIn='A1..AL262';headers=[1 1];display_opt=0;outdat=1;
            [X_b,Emmat_b,Exmat_b,filelist_b,outdata_b]=ReadInEEMs(filetype,ext,RangeIn,headers,display_opt,outdat);
            Exb=Exmat_b(1,:);
            Emb=Emmat_b(:,1);
            
            
            cd 'C:\PARAFAC\RAMAN'
            RamEx=350;
            filetype='Raman275';
            ext = 'csv';RangeIn='A1..B166';display_opt=0;outdat=1;
            [S_R,W_R,wave_R,filelist_R]=ReadInScans(filetype,ext,RangeIn,display_opt,outdat);
            
            cd 'C:\PARAFAC\UV'
            filetype='Abs'; ext='csv'; RangeIn='A1..B351';
            display_opt=0; outdat=1;
            [S_abs,W_abs,wave_abs,filelist_abs]=readinscans(filetype,ext,RangeIn,display_opt,outdat);
            
            cd 'C:\PARAFAC\Correction'
            Excor=xlsread('CorEX.csv');
            Emcor=xlsread('CorEM.csv');
            
            
            cd 'C:\PARAFAC'
            [LogNUM,LogTXT]=xlsread('SampleID.xlsx','SF-6-P');
            
            Log_EEMfile=LogTXT(:,7);
            Log_ABSfile=LogTXT(:,8);
            Log_Blkfile=LogTXT(:,9);
            Log_RAMfile=LogTXT(:,10);
            
            Pair_EEM_abs=[Log_EEMfile Log_ABSfile];
            Pair_EEM_R=[Log_EEMfile Log_RAMfile];
            Pair_EEM_bk=[Log_EEMfile Log_Blkfile];
            Pair_BLK_R=[Log_Blkfile Log_RAMfile];
            
            
            [Xabs,PL]=matchsamples(filelist_eem,filelist_abs,Pair_EEM_abs,X,S_abs);
            [Sr,PLr]=matchsamples(filelist_eem,filelist_R,Pair_EEM_R,X,S_R);
            [Xbk,PLbk]=matchsamples(filelist_eem,filelist_b,Pair_EEM_bk,X,X_b);
            [Sbk_R,PLbk_R]=matchsamples(filelist_b,filelist_R,Pair_BLK_R,X_b,S_R);
            
            A=[wave_abs;Xabs]
            B=Xbk;
            W=[wave_R;Sr];
            T=[wave_R;Sbk_R];


            [XcRU Arp IFCmat BcRU XcQS QS_RU]=FDOMcorrect(X,Ex,Em,Emcor,Excor,W,[],A,B,T,[],[]);


            originaldata.Ex=Ex';
            originaldata.Em=Em;
            originaldata.X=XcRU;
            originaldata.nEx=length(Ex);
            originaldata.nEm=length(Em);
            originaldata.nSample=size(X,1);
            Xs=smootheem(originaldata,[18 15],[15 15],[17 18],[19 18],0);
            
            [AnalysisData]=SplitData(Xs);
            [AnalysisData]=SplitHalfAnalysis(AnalysisData,(app.ComponentN.Value),'MyData.mat');
            SplitHalfValidation(AnalysisData,'1-2',app.ComponentN.Value);
            SplitHalfValidation(AnalysisData,'3-4',app.ComponentN.Value);
            [AnalysisData]=RandInitAnal(AnalysisData,app.ComponentN.Value,5);
            
            [FMax,B,C]=ModelOut(AnalysisData,app.ComponentN.Value,'C:\PARAFAC\EEMcomponents.xlsx');
            ComponentEEM(AnalysisData,app.ComponentN.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EEMviewTab = uitab(app.TabGroup);
            app.EEMviewTab.Title = 'EEMview';
            app.SampleNLabel = uilabel(app.EEMviewTab);
            app.SampleNLabel.Text = 'Sample N';
            app.SampleN = uieditfield(app.EEMviewTab, 'numeric');
            app.ComfirmButton = uibutton(app.EEMviewTab, 'push');
            app.ComfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ComfirmButtonPushed, true);
            app.ComfirmButton.Text = 'Comfirm';
            app.SSETab = uitab(app.TabGroup);
            app.SSETab.Title = 'SSE';
            app.CompareSSELabel = uilabel(app.SSETab);
            app.CompareSSELabel.Text = 'CompareSSE';
            app.ComfirmButton_2 = uibutton(app.SSETab, 'push');
            app.ComfirmButton_2.ButtonPushedFcn = createCallbackFcn(app, @ComfirmButton_2Pushed, true);
            app.ComfirmButton_2.Text = 'Comfirm';
            app.ModelOutTab = uitab(app.TabGroup);
            app.ModelOutTab.Title = 'ModelOut';
            app.ComponentNEditFieldLabel = uilabel(app.ModelOutTab);
            app.ComponentNEditFieldLabel.Text = 'Component N';
            app.ComponentN = uieditfield(app.ModelOutTab, 'numeric');
            app.ComfirmButton_3 = uibutton(app.ModelOutTab, 'push');
            app.ComfirmButton_3.ButtonPushedFcn = createCallbackFcn(app, @ComfirmButton_3Pushed, true);
            app.ComfirmButton_3.Text = 'Comfirm';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PARAFACforMATLB

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end